classdef UnivariateNHB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ConfidenceCheckBox_2            matlab.ui.control.CheckBox
        ConfidenceCheckBox              matlab.ui.control.CheckBox
        ReplicatesEditField             matlab.ui.control.NumericEditField
        ReplicatesEditFieldLabel        matlab.ui.control.Label
        EnterMaximumEditField           matlab.ui.control.NumericEditField
        EnterMaximumEditFieldLabel      matlab.ui.control.Label
        EnterStepLengthwEditField       matlab.ui.control.NumericEditField
        EnterStepLengthwEditFieldLabel  matlab.ui.control.Label
        MaximumyvalueEditField          matlab.ui.control.NumericEditField
        MaximumyvalueEditFieldLabel     matlab.ui.control.Label
        MaximumxvalueEditField          matlab.ui.control.NumericEditField
        MaximumxvalueEditFieldLabel     matlab.ui.control.Label
        MinimumyvalueEditField          matlab.ui.control.NumericEditField
        MinimumyvalueEditFieldLabel     matlab.ui.control.Label
        txtfiletoloaddatafromEditField  matlab.ui.control.EditField
        txtfiletoloaddatafromEditFieldLabel  matlab.ui.control.Label
        TextFiletostoreDatatoEditField  matlab.ui.control.EditField
        TextFiletostoreDatatoEditFieldLabel  matlab.ui.control.Label
        MinimumxvalueEditField          matlab.ui.control.NumericEditField
        MinimumxvalueEditFieldLabel     matlab.ui.control.Label
        SortDataSwitch                  matlab.ui.control.Switch
        SortDataSwitchLabel             matlab.ui.control.Label
        ConfidenceIntervalSwitch        matlab.ui.control.Switch
        ConfidenceIntervalSwitchLabel   matlab.ui.control.Label
        EdgeCorrectionSwitch            matlab.ui.control.Switch
        EdgeCorrectionSwitchLabel       matlab.ui.control.Label
        BeginAnalysisButton             matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BeginAnalysisButton
        function BeginAnalysisButtonPushed(app, event)
            assignin('base', 'x_min', app.MinimumxvalueEditField.Value)
            assignin('base', 'x_max', app.MaximumxvalueEditField.Value)
            assignin('base', 'y_min', app.MinimumyvalueEditField.Value)
            assignin('base', 'y_max', app.MaximumyvalueEditField.Value)
            assignin('base', 'step_length', app.EnterStepLengthwEditField.Value)
            assignin('base', 'max_s', app.EnterMaximumEditField.Value)
            assignin('base', 'edge_method', app.EdgeCorrectionSwitch.Value)
            assignin('base', 'sort_method', app.SortDataSwitch.Value)
            assignin('base', 'confidence_interval', app.ConfidenceIntervalSwitch.Value)
            assignin('base', 'limit_95', app.ConfidenceCheckBox.Value)
            assignin('base', 'limit_99', app.ConfidenceCheckBox_2.Value)
            assignin('base', 'input_filename', app.txtfiletoloaddatafromEditField.Value)
            assignin('base', 'output_filename', app.TextFiletostoreDatatoEditField.Value)
            assignin('base', 'n_simul', app.ReplicatesEditField.Value)            
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BeginAnalysisButton = uibutton(app.UIFigure, 'push');
            app.BeginAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @BeginAnalysisButtonPushed, true);
            app.BeginAnalysisButton.Text = 'Begin Analysis';
            app.EdgeCorrectionSwitchLabel = uilabel(app.UIFigure);
            app.EdgeCorrectionSwitchLabel.Text = 'Edge Correction';
            app.EdgeCorrectionSwitch = uiswitch(app.UIFigure, 'slider');
            app.ConfidenceIntervalSwitchLabel = uilabel(app.UIFigure);
            app.ConfidenceIntervalSwitchLabel.Text = 'Confidence Interval';
            app.ConfidenceIntervalSwitch = uiswitch(app.UIFigure, 'slider');
            app.SortDataSwitchLabel = uilabel(app.UIFigure);
            app.SortDataSwitchLabel.Text = 'Sort Data';
            app.SortDataSwitch = uiswitch(app.UIFigure, 'slider');
            app.MinimumxvalueEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumxvalueEditFieldLabel.Text = 'Minimum x-value';
            app.MinimumxvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.TextFiletostoreDatatoEditFieldLabel = uilabel(app.UIFigure);
            app.TextFiletostoreDatatoEditFieldLabel.Text = 'Text File to store Data to';
            app.TextFiletostoreDatatoEditField = uieditfield(app.UIFigure, 'text');
            app.txtfiletoloaddatafromEditFieldLabel = uilabel(app.UIFigure);
            app.txtfiletoloaddatafromEditFieldLabel.Text = 'txt file to load data from';
            app.txtfiletoloaddatafromEditField = uieditfield(app.UIFigure, 'text');
            app.MinimumyvalueEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumyvalueEditFieldLabel.Text = 'Minimum y-value';
            app.MinimumyvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumxvalueEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumxvalueEditFieldLabel.Text = 'Maximum x-value';
            app.MaximumxvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumyvalueEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumyvalueEditFieldLabel.Text = 'Maximum y-value';
            app.MaximumyvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterStepLengthwEditFieldLabel = uilabel(app.UIFigure);
            app.EnterStepLengthwEditFieldLabel.Text = 'Enter Step Length (w):';
            app.EnterStepLengthwEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterMaximumEditFieldLabel = uilabel(app.UIFigure);
            app.EnterMaximumEditFieldLabel.Text = 'Enter Maximum';
            app.EnterMaximumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReplicatesEditFieldLabel = uilabel(app.UIFigure);
            app.ReplicatesEditFieldLabel.Text = 'Replicates';
            app.ReplicatesEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConfidenceCheckBox = uicheckbox(app.UIFigure);
            app.ConfidenceCheckBox.Text = '95% Confidence';
            app.ConfidenceCheckBox_2 = uicheckbox(app.UIFigure);
            app.ConfidenceCheckBox_2.Text = '99% Confidence';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UnivariateNHB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end